classdef SATINN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainGrid                       matlab.ui.container.GridLayout
        SATINNTitle                    matlab.ui.control.Label
        MainTabs                       matlab.ui.container.TabGroup
        InputsTab                      matlab.ui.container.Tab
        ResetButton                    matlab.ui.control.Button
        PretrainedneuralnetworksPanel  matlab.ui.container.Panel
        PretrainednetworksGrid         matlab.ui.container.GridLayout
        TubulestagesLabel              matlab.ui.control.Label
        PTNTubuleDropDown              matlab.ui.control.DropDown
        CelltypesLabel                 matlab.ui.control.Label
        PTNCellDropDown                matlab.ui.control.DropDown
        PTNCellCustomName              matlab.ui.control.Label
        PTNTubuleCustomName            matlab.ui.control.Label
        ProcessStatus                  matlab.ui.control.Label
        HTMLInputs                     matlab.ui.control.HTML
        ProcessLamp                    matlab.ui.control.Lamp
        ProcessButton                  matlab.ui.control.Button
        ImagesPanel                    matlab.ui.container.Panel
        InputImagesGrid                matlab.ui.container.GridLayout
        HoechstButton                  matlab.ui.control.Button
        ACTA2Button                    matlab.ui.control.Button
        HoechstSegmentationButton      matlab.ui.control.Button
        ACRV1Button                    matlab.ui.control.Button
        HoechstLamp                    matlab.ui.control.Lamp
        HoechstSegmentationLamp        matlab.ui.control.Lamp
        ACTA2Lamp                      matlab.ui.control.Lamp
        ACRV1Lamp                      matlab.ui.control.Lamp
        ACTA2Axis                      matlab.ui.control.UIAxes
        HoechstSegmentationAxis        matlab.ui.control.UIAxes
        ACRV1Axis                      matlab.ui.control.UIAxes
        HoechstAxis                    matlab.ui.control.UIAxes
        OutputsCellsTab                matlab.ui.container.Tab
        HTMLOutputsCells               matlab.ui.control.HTML
        CellstatstableoptionsPanel     matlab.ui.container.Panel
        CellstatstableoptionsGrid      matlab.ui.container.GridLayout
        CelldataSaveButton             matlab.ui.control.Button
        CellstatstableRandomizeButton  matlab.ui.control.Button
        ShowinterstitialcellsCheckBox  matlab.ui.control.CheckBox
        ShowintratubularcellsCheckBox  matlab.ui.control.CheckBox
        CellstatstablePanel            matlab.ui.container.Panel
        CellsampleoutputPanel          matlab.ui.container.Panel
        CellsampleoutputGrid           matlab.ui.container.GridLayout
        CellclassificationclustergramPanel  matlab.ui.container.Panel
        CellclassificationimageAxis    matlab.ui.control.UIAxes
        CellsegmentationimageAxis      matlab.ui.control.UIAxes
        CelltypecountshistogramAxis    matlab.ui.control.UIAxes
        CellstatsplotAxis              matlab.ui.control.UIAxes
        CellclassificationimageLegendAxis  matlab.ui.control.UIAxes
        OutputsTubulesTab              matlab.ui.container.Tab
        TubulesampleoutputPanel        matlab.ui.container.Panel
        TubulesegmentationimageAxis    matlab.ui.control.UIAxes
        TubulestatstablePanel          matlab.ui.container.Panel
        HTMLOutputsTubules             matlab.ui.control.HTML
        VersionText                    matlab.ui.control.Label
        GithubText                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        imh     % Hoechst input image
        imhbwl  % Hoechst segmentation input image
        ima     % Acta2 input image
        imv     % Acrv1 input image
        
        TC      % Output cell stats table
        TT      % Output tubule stats table
        
        imabwd  % Acta2 segmentation output image
        cells   % Stack of extracted single cell images
        tubules % Stack of extracted single tubule images
    end
    
    properties (Access = public)
        NNfile = 'cellnet_h-210731.mat';  % Location of pretrained neural net *.mat file
        NN
    end
    
    methods (Access = private)
        
        function inputCheck(app)
            if all(cellfun(@(x) ~isempty(x), {app.imh, app.ima, app.imv}))
                app.ProcessButton.Enable = 'on';
                app.ProcessLamp.Color = 'red';
            end
        end
        
        % Make cell stats table
        function updateCellTable(app, r)
            ax = app.CellstatstablePanel;
            % Source data
            T = app.TC;
            if ~app.ShowintratubularcellsCheckBox.Value
                T(T.InTubule ~= 0, :) = [];
            end
            if ~app.ShowinterstitialcellsCheckBox.Value
                T(T.InTubule == 0, :) = [];
            end
            % Filter data
            if size(T, 1) > 20 && r
                T = T(randsample(size(T, 1), 20, false), :);
            elseif size(T, 1) > 20 && ~r
                T = T(1:20, :);
            end
            if size(T, 2) > 12, T = T(:, 1:12); end
            % Plot data
            u = uitable(ax, 'data', T);
            panelsize = get(ax, 'position');
            set(u, 'position', [10, 10, panelsize(3) - 20, panelsize(4) - 37]);
            set(ax, 'Title', sprintf('Cell stats table, previewing %i cells x %i features (of %i cells x %i features)', size(T, 1), size(T, 2), size(app.TC, 1), size(app.TC, 2)));
        end
        
        % Make cell plots
        function updateCellPlots(app)

            % Skip all plotting if no data selected
            if ~app.ShowinterstitialcellsCheckBox.Value && ~app.ShowintratubularcellsCheckBox.Value
                app.CellsampleoutputPanel.Enable = 'off';
                return
            end

            app.CellsampleoutputPanel.Enable = 'on';
            % Source data
            T = app.TC;
            if ~app.ShowintratubularcellsCheckBox.Value
                T(T.InTubule ~= 0, :) = [];
            end
            if ~app.ShowinterstitialcellsCheckBox.Value
                T(T.InTubule == 0, :) = [];
            end
            
            % Find classes
            cstr = app.NN.net.Layers(end, 1).Classes;
            % Determine colormap
            if ~isempty(app.PTNCellDropDown.Value)
                cmap = parula(10);
                cmap = cmap(1:size(cstr, 1), :);
            else
                cmap = parula(size(cstr, 1));
            end
            
            % Show cell segmentation image
            ax = app.CellsegmentationimageAxis;
            im = app.imhbwl;
            im(~ismember(im, [0; T.GlobalID])) = 0;
            imagesc(ax, label2rgb(im, 'parula', 'w'));
            axis(ax, 'image')
            ax.Interactions = [];
            
            % Show classified cell type image
            ax = app.CellclassificationimageAxis;
            Lnum = uint8(zeros(size(app.imhbwl)));
            for i = 1:size(cstr, 1)
                val = T.GlobalID(T.Celltype == cstr(i));
                Lnum(ismember(im, val)) = i;
            end
            imagesc(ax, label2rgb(Lnum, cmap, 'w'))
            axis(ax, 'image')
            ax.Interactions = [];

            % Show classified cell type heatmap
            if size(T, 1) > 300
                X = T{randsample(size(T, 1), 300, false), cellstr(cstr)};
            else
                X = T{:, cellstr(cstr)};
            end
            f = figure('Visible', 'off');
            [f, ~, perm] = dendrogram(linkage(X), 0);
            clf(f);
            hm = heatmap(app.CellclassificationclustergramPanel, X(perm, :));
            set(hm, 'colormap', parula);
            set(hm, 'xdisplaylabels', cellstr(cstr));
            set(hm, 'ydisplaylabels', repmat({''}, size(perm, 2), 1))
            set(hm, 'gridvisible', 'off')
            set(hm, 'colorbarvisible', 'off')
            set(hm, 'title', sprintf('Classification\nprobability\nheatmap'))


            % Add custom legend (in adjacent but separate fig)
            ax = app.CellclassificationimageLegendAxis;
            for i = 1:size(cstr, 1)
                plot(ax, NaN, NaN, 'linestyle', 'none', 'markersize', 20, 'markeredgecolor', cmap(i, :), 'marker', '.')
                hold(ax, 'on')
            end
            legend(ax, cstr, 'location', 'northeast', 'edgecolor', 'none', 'color', 'none')
            hold(ax, 'off')
            
            % Cell type counts histogram
            ax = app.CelltypecountshistogramAxis;
            dat = zeros(size(cstr, 1), 1);
            for i = 1:size(cstr, 1)
                dat(i) = sum(T.Celltype == cstr(i));
            end
            b = bar(ax, cstr, dat, 'facecolor', 'flat');
            b.CData = cmap;
            xtickangle(ax, 40);
            
            % Cell area distribution
            ax = app.CellstatsplotAxis;
            for i = 1:size(cstr, 1)
                boxchart(ax, i*ones(sum(T.Celltype == cstr(i)), 1), T.Area(T.Celltype == cstr(i)), 'BoxFaceColor', cmap(i, :), 'markercolor', cmap(i, :), 'JitterOutliers', 'on', 'MarkerStyle', '.', 'MarkerSize', 3)
                hold(ax, 'on')
            end
            xticks(ax, 1:size(cstr, 1))
            xticklabels(ax, cellstr(cstr))
            xtickangle(ax, 45)
            hold(ax, 'off')
        end

        
        % Make tubule stats table
        function updateTubuleTable(app, r)
            ax = app.TubulestatstablePanel;
            T = app.TT;
            if size(T, 1) > 20 && r
                T = T(randsample(size(T, 1), 20, false), :);
            elseif size(T, 1) > 20 && ~r
                T = T(1:20, :);
            end
            if size(T, 2) > 12, T = T(:, 1:12); end
            u = uitable(ax, 'data', T);
            panelsize = get(ax, 'position');
            set(u, 'position', [10, 10, panelsize(3) - 20, panelsize(4) - 37]);
            set(ax, 'Title', sprintf('Tubule stats table, previewing %i tubules x %i features (of %i tubules x %i features)', size(T, 1), size(T, 2), size(app.TT, 1), size(app.TT, 2)));
        end
        
        % Make tubule plots
        function updateTubulePlots(app)
            % Show tubule segmentation
            ax = app.TubulesegmentationimageAxis;
            imagesc(ax, label2rgb(bwlabel(app.imabwd), 'parula', 'w'));
            axis(ax, 'image')
            ax.Interactions = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HoechstButton
        function HoechstButtonPushed(app, event)
            [f, p] = uigetfile('*.tif;*.tiff;*.png', 'Select Hoechst image');
            if isnumeric(p) || isnumeric(f), return, end
            app.HoechstLamp.Color = 'yellow'; drawnow;
            app.imh = imread(fullfile(p, f));
            imagesc(app.HoechstAxis, app.imh);
            axis(app.HoechstAxis, 'image')
            app.HoechstLamp.Color = 'green'; drawnow;
            app.HoechstAxis.Interactions = [];
            inputCheck(app)
        end

        % Button pushed function: HoechstSegmentationButton
        function HoechstSegmentationButtonPushed(app, event)
            [f, p] = uigetfile('*.tif;*.tiff;*.png', 'Select Hoechst Segmentation image');
            if isnumeric(p) || isnumeric(f), return, end
            app.HoechstSegmentationLamp.Color = 'yellow'; drawnow;
            if islogical(app.imhbwl), app.imhbwl = bwlabel(app.imhbwl); end
            app.imhbwl = imread(fullfile(p, f));
            imagesc(app.HoechstSegmentationAxis, app.imhbwl);
            axis(app.HoechstSegmentationAxis, 'image')
            app.HoechstSegmentationLamp.Color = 'green'; drawnow;
            app.HoechstSegmentationAxis.Interactions = [];
            inputCheck(app)
        end

        % Button pushed function: ACRV1Button
        function ACRV1ButtonPushed(app, event)
            [f, p] = uigetfile('*.tif;*.tiff;*.png', 'Select Acrv1 image');
            if isnumeric(p) || isnumeric(f), return, end
            app.ACRV1Lamp.Color = 'yellow'; drawnow;
            app.imv = imread(fullfile(p, f));
            imagesc(app.ACRV1Axis, app.imv);
            axis(app.ACRV1Axis, 'image')
            app.ACRV1Lamp.Color = 'green'; drawnow;
            app.ACRV1Axis.Interactions = [];
            inputCheck(app)
        end

        % Button pushed function: ACTA2Button
        function ACTA2ButtonPushed(app, event)
            [f, p] = uigetfile('*.tif;*.tiff;*.png', 'Select Acta2 image');
            if isnumeric(p) || isnumeric(f), return, end
            app.ACTA2Lamp.Color = 'yellow'; drawnow;
            app.ima = imread(fullfile(p, f));
            imagesc(app.ACTA2Axis, app.ima);
            axis(app.ACTA2Axis, 'image')
            app.ACTA2Lamp.Color = 'green'; drawnow;
            app.ACTA2Axis.Interactions = [];
            inputCheck(app)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % Generic error checking
            if ~(isequal(size(app.imh), size(app.imhbwl), size(app.ima), size(app.imv)) || ...
                    (isequal(size(app.imh), size(app.ima), size(app.imv)) && isempty(app.imhbwl)))
            emsg = 'Image channels are not all of the same size. Ensure all input images are from the same source.';
            errordlg(emsg)
            app.ProcessLamp.Color = 'red';
            app.ProcessStatus.Text = ['Error: ', emsg]; drawnow;
            return
            end
            
            if isempty(app.imhbwl)
                a = questdlg('No Hoechst (Segmentation) image specified. Continue to use native segmentation or exit to provide your own image.', ...
                    [], 'Continue', 'Exit', 'Continue');
                if strcmp(a, 'Exit'), return; end
            end
            
            % Status update
            app.ProcessLamp.Color = 'yellow';
            app.ProcessStatus.Text = 'Status: Working on it...'; drawnow;
            app.ProcessButton.Enable = 'off';
            
            %%%%%%%%%% Operations %%%%%%%%%%
            if isempty(app.imhbwl)
                % TODO: native cell segmentation
                app.ProcessStatus.Text = 'Status: Native cell segmentation...'; drawnow;
                app.imhbwl = segment_cells(app.imh, 'figures', false, 'verbose', false);
            end
            
            app.ProcessStatus.Text = 'Status: Load neural network...'; drawnow;
            app.NN = load(app.NNfile); % Load specified pre-trained NN
            cimsize = app.NN.net.Layers(1, 1).InputSize;
            
            %%%% Tubule segmentation %%%%
            app.ProcessStatus.Text = 'Status: Tubule segmentation...'; drawnow;
            [im, app.imabwd, imaR] = setup_tubclass(app.imh, app.ima, app.imv);
            
            %%%% Cell processing %%%%
            app.ProcessStatus.Text = 'Status: Cell image processing...'; drawnow;
            % Generate relative apical-basal distance data for each tubule. This
            % gives us a normalized method to locate cells within and between
            % tubules.
            imabwdist = bwdist(~app.imabwd);
            for i = 1:size(imaR, 1)
                imabwdist(app.imabwd == i) = rescale(imabwdist(app.imabwd == i), 0, 65535);
            end
            % Establish cell image containing Hoe, A/B position, and Acta2 data
            % (preserved from previous builds but currently unused).
            im2 = cat(3, im(:, :, 2), imabwdist, im(:, :, 3));
            % Read cellpose segmentation data and build stats table
            imhR = regionprops(app.imhbwl, {'Area', 'BoundingBox', 'Centroid', 'Circularity', 'Eccentricity', ...
                'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'Perimeter'});
            % Extract individual cell images from entire slide (warning: this might
            % take a while if there are a lot of cells).
            ims = extract_objects(im2, [], imhR, [], cimsize(1));
            % Generate and save cell image stack as Matlab file. Specifically, the
            % `cells` variable will be a 50x50x(1 or 3)xN stack of N cell images.
            app.cells = cat(4, ims{:, 1});
            
            %%%% Classification %%%%
            app.ProcessStatus.Text = 'Status: Cell type classification...'; drawnow;
            [p, s] = classify(app.NN.net, app.cells(:, :, 1:cimsize(3), :));
            
            %%%% Build cell stats table %%%%
            app.ProcessStatus.Text = 'Status: Build cell stats table...'; drawnow;
            app.TC = array2table(s);
            app.TC.Properties.VariableNames = cellstr(app.NN.net.Layers(15, 1).Classes);
            app.TC.Celltype = categorical(p); % predicted cell type
            app.TC.RelPos = zeros(size(s, 1), 1); % A/B position
            for i = 1:size(s, 1)
                tmp = squeeze(app.cells(:, :, :, i));
                app.TC.RelPos(i) = mean(tmp(:, :, 2), 'all');
            end
            
            % Incorporate physical cell stats into table
            imhT = struct2table(imhR);
            app.TC = [app.TC, imhT];
            
            % Add cell indices
            app.TC.GlobalID = (1:size(s, 1))'; % unique indicator of cells w.r.t. whole image
            app.TC.InTubule = zeros(size(s, 1), 1); % indicator of tubule that contains this particular cell (0 if not in tubule)
            imabwdl = bwlabel(app.imabwd);
            for i = 1:size(app.TC, 1)
                app.TC.InTubule(i) = imabwdl(round(app.TC.Centroid(i, 2)), round(app.TC.Centroid(i, 1))); % assign cell to tubule
            end
            app.TC = movevars(app.TC, {'GlobalID', 'InTubule'}, 'before', 1);
            
            % Calculate cell relative orientation
            app.TC.RelOrient = zeros(size(s, 1), 1); % cell orientation relative to A/B
            for i = 1:size(s, 1)
                if app.TC.InTubule(i) ~= 0
                    v = app.TC.Centroid(i, :) - imaR(app.TC.InTubule(i)).Centroid;
                    app.TC.RelOrient(i) = min([abs(app.TC.Orientation(i) - atan2(v(2), v(1))), abs(app.TC.Orientation(i) - atan2(-v(2), -v(1)))]);
                end
            end
            
            %%%% Build tubule stats table %%%%
            
            app.TT = struct2table(imaR);
            app.TT.TubuleID = (1:size(app.TT, 1))';
            app.TT = movevars(app.TT, 'TubuleID', 'before', 1);
            app.TT.RadiusTubuleAsCircle = sqrt(app.TT.Area/pi);
            app.TT.RadiusTubuleScaled = nan(size(app.TT, 1), 1);
            app.TT.RadiusLumenScaled = nan(size(app.TT, 1), 1);
            for i = 1:size(app.TT, 1)
                val = app.TC{app.TC.InTubule == i, 'RelPos'};
                app.TT.RadiusTubuleScaled(i) = median(mink(val, ceil(length(val)*0.05)), 'omitnan');
                app.TT.RadiusLumenScaled(i)  = median(maxk(val, ceil(length(val)*0.05)), 'omitnan');
            end
            app.TT.RadiusLumenAsCircle = app.TT.RadiusTubuleAsCircle .* app.TT.RadiusLumenScaled ./ app.TT.RadiusTubuleScaled;
            
            %%%% Outputs %%%%
            app.ProcessStatus.Text = 'Status: Generating figures...'; drawnow;
            
            % Update cell plots %
            updateCellTable(app, false)
            updateCellPlots(app)
            
            % Update tubule plots %%
            updateTubuleTable(app, false)
            updateTubulePlots(app)

            
            %%%%%%%%%% %%%%%%%%%%
            
            % Enable next steps
            app.CellstatstablePanel.Enable = 'on';
            app.CellsampleoutputPanel.Enable = 'on';
            app.CellstatstableoptionsPanel.Enable = 'on';
            app.TubulestatstablePanel.Enable = 'on';
            app.TubulesampleoutputPanel.Enable = 'on';
                        
            % Status update
            app.ProcessLamp.Color = 'green';
            app.ProcessStatus.Text = 'Status: Complete. Go to the Outputs tab to view the results.'; drawnow;
        end

        % Button pushed function: CelldataSaveButton
        function CelldataSaveButtonPushed(app, event)
            if isempty(app.TC)
                errordlg('No data table found.')
                return
            end
            [f, p] = uiputfile({'*.csv', 'Comma-separated values (*.csv)'; ...
                '*.txt', 'Plain text file (*.txt)'});
            writetable(app.TC, fullfile(p, f))
        end

        % Value changed function: PTNCellDropDown
        function selectTrainingNetwork(app, event)
            app.NNfile = app.PTNCellDropDown.Value;
            if isempty(app.NNfile)
                [f, p] = uigetfile('*.mat', 'Select pre-trained neural network file.');
                if isnumeric(p) || isnumeric(f)
                    warndlg('No file specified.')
                    app.NNfile = []; app.PTNCellCustomName.Text = '';
                    app.PTNCellDropDown.Value = app.PTNCellDropDown.ItemsData(1);
                    return
                end
                app.NNfile = fullfile(p, f);
                % Simple error check
                m = matfile(app.NNfile);
                if ~isprop(m, 'net')
                    errordlg('Pre-trained neural network does not exist or is not named correctly. Ensure the variable `net` exists in the .mat file.')
                    app.NNfile = []; app.PTNCellCustomName.Text = '';
                    app.PTNCellDropDown.Value = app.PTNCellDropDown.ItemsData(1);
                    return
                end
                if ~isa(m.net, 'SeriesNetwork')
                    errordlg('The `net` variable is not a SeriesNetwork.')
                    app.NNfile = []; app.PTNCellCustomName.Text = '';
                    app.PTNCellDropDown.Value = app.PTNCellDropDown.ItemsData(1);
                    return
                end
                % Passed error checks, confirm name
                app.PTNCellCustomName.Text = f;
            else
                app.PTNCellCustomName.Text = '';
            end
            
        end

        % Value changed function: ShowinterstitialcellsCheckBox, 
        % ...and 1 other component
        function updateCellOptions(app, event)
            updateCellTable(app, false)
            updateCellPlots(app)
        end

        % Button pushed function: CellstatstableRandomizeButton
        function CellstatstableRandomizeButtonPushed(app, event)
            updateCellTable(app, true)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset input page
            cla(app.HoechstAxis)
            cla(app.HoechstSegmentationAxis)
            cla(app.ACTA2Axis)
            cla(app.ACRV1Axis)
            app.imh = [];
            app.imhbwl = [];
            app.ima = [];
            app.imv = [];
            app.HoechstLamp.Color = 'red';
            app.HoechstSegmentationLamp.Color = 'red';
            app.ACTA2Lamp.Color = 'red';
            app.ACRV1Lamp.Color = 'red';
            app.ProcessButton.Enable = 'off';
            app.ProcessLamp.Color = [0.8, 0.8, 0.8];
            app.ProcessStatus.Text = 'Status: Waiting for image input.';

            % Disable next steps
            app.CellstatstablePanel.Enable = 'off';
            app.CellsampleoutputPanel.Enable = 'off';
            app.CellstatstableoptionsPanel.Enable = 'off';
            app.TubulestatstablePanel.Enable = 'off';
            app.TubulesampleoutputPanel.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGrid = uigridlayout(app.UIFigure);
            app.GithubText = uilabel(app.MainGrid);
            app.GithubText.Text = 'http://github.com/conradlab/SATINN';
            app.VersionText = uilabel(app.MainGrid);
            app.VersionText.Text = '2205220603';
            app.MainTabs = uitabgroup(app.MainGrid);
            app.InputsTab = uitab(app.MainTabs);
            app.InputsTab.Tooltip = {''};
            app.InputsTab.Title = 'Inputs';
            app.ImagesPanel = uipanel(app.InputsTab);
            app.ImagesPanel.Title = 'Images';
            app.InputImagesGrid = uigridlayout(app.ImagesPanel);
            app.HoechstAxis = uiaxes(app.InputImagesGrid);
            app.HoechstAxis.XTick = [];
            app.HoechstAxis.YTick = [];
            app.HoechstAxis.Visible = 'off';
            app.ACRV1Axis = uiaxes(app.InputImagesGrid);
            app.ACRV1Axis.XLim = [1 2];
            app.ACRV1Axis.XTick = [];
            app.ACRV1Axis.YTick = [];
            app.ACRV1Axis.Visible = 'off';
            app.HoechstSegmentationAxis = uiaxes(app.InputImagesGrid);
            app.HoechstSegmentationAxis.XTick = [];
            app.HoechstSegmentationAxis.YTick = [];
            app.HoechstSegmentationAxis.Visible = 'off';
            app.ACTA2Axis = uiaxes(app.InputImagesGrid);
            app.ACTA2Axis.XTick = [];
            app.ACTA2Axis.YTick = [];
            app.ACTA2Axis.Visible = 'off';
            app.ACRV1Lamp = uilamp(app.InputImagesGrid);
            app.ACTA2Lamp = uilamp(app.InputImagesGrid);
            app.HoechstSegmentationLamp = uilamp(app.InputImagesGrid);
            app.HoechstLamp = uilamp(app.InputImagesGrid);
            app.ACRV1Button = uibutton(app.InputImagesGrid, 'push');
            app.ACRV1Button.ButtonPushedFcn = createCallbackFcn(app, @ACRV1ButtonPushed, true);
            app.ACRV1Button.Text = 'ACRV1';
            app.HoechstSegmentationButton = uibutton(app.InputImagesGrid, 'push');
            app.HoechstSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @HoechstSegmentationButtonPushed, true);
            app.HoechstSegmentationButton.Text = 'Hoechst (Segmentation)';
            app.ACTA2Button = uibutton(app.InputImagesGrid, 'push');
            app.ACTA2Button.ButtonPushedFcn = createCallbackFcn(app, @ACTA2ButtonPushed, true);
            app.ACTA2Button.Text = 'ACTA2';
            app.HoechstButton = uibutton(app.InputImagesGrid, 'push');
            app.HoechstButton.ButtonPushedFcn = createCallbackFcn(app, @HoechstButtonPushed, true);
            app.HoechstButton.Text = 'Hoechst';
            app.ProcessButton = uibutton(app.InputsTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.ProcessLamp = uilamp(app.InputsTab);
            app.HTMLInputs = uihtml(app.InputsTab);
            app.HTMLInputs.HTMLSource = 'SATINN1.html';
            app.ProcessStatus = uilabel(app.InputsTab);
            app.ProcessStatus.Text = 'Status: Waiting for image input.';
            app.PretrainedneuralnetworksPanel = uipanel(app.InputsTab);
            app.PretrainedneuralnetworksPanel.Title = 'Pre-trained neural networks';
            app.PretrainednetworksGrid = uigridlayout(app.PretrainedneuralnetworksPanel);
            app.PTNTubuleCustomName = uilabel(app.PretrainednetworksGrid);
            app.PTNTubuleCustomName.Enable = 'off';
            app.PTNTubuleCustomName.Text = '';
            app.PTNCellCustomName = uilabel(app.PretrainednetworksGrid);
            app.PTNCellCustomName.Text = '';
            app.PTNCellDropDown = uidropdown(app.PretrainednetworksGrid);
            app.PTNCellDropDown.Items = {'Germ cells (Hoechst only)', 'Germ cells (Hoechst, Acta2, bwdist)', 'Custom'};
            app.PTNCellDropDown.ItemsData = {'cellnet_h-210731.mat', 'cellnet-210409.mat', ''};
            app.PTNCellDropDown.ValueChangedFcn = createCallbackFcn(app, @selectTrainingNetwork, true);
            app.PTNCellDropDown.Value = 'cellnet_h-210731.mat';
            app.CelltypesLabel = uilabel(app.PretrainednetworksGrid);
            app.CelltypesLabel.Text = 'Cell types:';
            app.PTNTubuleDropDown = uidropdown(app.PretrainednetworksGrid);
            app.PTNTubuleDropDown.Items = {'None (Do not classify)'};
            app.PTNTubuleDropDown.Enable = 'off';
            app.PTNTubuleDropDown.Value = 'None (Do not classify)';
            app.TubulestagesLabel = uilabel(app.PretrainednetworksGrid);
            app.TubulestagesLabel.Enable = 'off';
            app.TubulestagesLabel.Text = 'Tubule stages:';
            app.ResetButton = uibutton(app.InputsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputsCellsTab = uitab(app.MainTabs);
            app.OutputsCellsTab.Tooltip = {''};
            app.OutputsCellsTab.Title = 'Outputs (Cells)';
            app.CellsampleoutputPanel = uipanel(app.OutputsCellsTab);
            app.CellsampleoutputPanel.Enable = 'off';
            app.CellsampleoutputPanel.Title = 'Sample cell outputs';
            app.CellsampleoutputGrid = uigridlayout(app.CellsampleoutputPanel);
            app.CellclassificationimageLegendAxis = uiaxes(app.CellsampleoutputGrid);
            app.CellclassificationimageLegendAxis.XTick = [];
            app.CellclassificationimageLegendAxis.YTick = [];
            app.CellclassificationimageLegendAxis.Visible = 'off';
            app.CellstatsplotAxis = uiaxes(app.CellsampleoutputGrid);
            title(app.CellstatsplotAxis, 'Cell area (px)')
            app.CelltypecountshistogramAxis = uiaxes(app.CellsampleoutputGrid);
            title(app.CelltypecountshistogramAxis, 'Cell type counts')
            app.CellsegmentationimageAxis = uiaxes(app.CellsampleoutputGrid);
            title(app.CellsegmentationimageAxis, 'Segmentation image')
            app.CellsegmentationimageAxis.XTick = [];
            app.CellsegmentationimageAxis.YTick = [];
            app.CellclassificationimageAxis = uiaxes(app.CellsampleoutputGrid);
            title(app.CellclassificationimageAxis, 'Classification image')
            app.CellclassificationimageAxis.XTick = [];
            app.CellclassificationimageAxis.YTick = [];
            app.CellclassificationclustergramPanel = uipanel(app.CellsampleoutputGrid);
            app.CellstatstablePanel = uipanel(app.OutputsCellsTab);
            app.CellstatstablePanel.Enable = 'off';
            app.CellstatstablePanel.Title = 'Cell stats table';
            app.CellstatstableoptionsPanel = uipanel(app.OutputsCellsTab);
            app.CellstatstableoptionsPanel.Enable = 'off';
            app.CellstatstableoptionsPanel.Title = 'Cell table options';
            app.CellstatstableoptionsGrid = uigridlayout(app.CellstatstableoptionsPanel);
            app.ShowintratubularcellsCheckBox = uicheckbox(app.CellstatstableoptionsGrid);
            app.ShowintratubularcellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateCellOptions, true);
            app.ShowintratubularcellsCheckBox.Text = 'Show intratubular cells';
            app.ShowintratubularcellsCheckBox.Value = true;
            app.ShowinterstitialcellsCheckBox = uicheckbox(app.CellstatstableoptionsGrid);
            app.ShowinterstitialcellsCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateCellOptions, true);
            app.ShowinterstitialcellsCheckBox.Text = 'Show interstitial cells';
            app.CellstatstableRandomizeButton = uibutton(app.CellstatstableoptionsGrid, 'push');
            app.CellstatstableRandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @CellstatstableRandomizeButtonPushed, true);
            app.CellstatstableRandomizeButton.Text = 'Randomize';
            app.CelldataSaveButton = uibutton(app.CellstatstableoptionsGrid, 'push');
            app.CelldataSaveButton.ButtonPushedFcn = createCallbackFcn(app, @CelldataSaveButtonPushed, true);
            app.CelldataSaveButton.Text = 'Save';
            app.HTMLOutputsCells = uihtml(app.OutputsCellsTab);
            app.HTMLOutputsCells.HTMLSource = 'SATINN2.html';
            app.OutputsTubulesTab = uitab(app.MainTabs);
            app.OutputsTubulesTab.Title = 'Outputs (Tubules)';
            app.HTMLOutputsTubules = uihtml(app.OutputsTubulesTab);
            app.HTMLOutputsTubules.HTMLSource = 'SATINN3.html';
            app.TubulestatstablePanel = uipanel(app.OutputsTubulesTab);
            app.TubulestatstablePanel.Enable = 'off';
            app.TubulestatstablePanel.Title = 'Tubule stats table';
            app.TubulesampleoutputPanel = uipanel(app.OutputsTubulesTab);
            app.TubulesampleoutputPanel.Enable = 'off';
            app.TubulesampleoutputPanel.Title = 'Sample tubule outputs';
            app.TubulesegmentationimageAxis = uiaxes(app.TubulesampleoutputPanel);
            title(app.TubulesegmentationimageAxis, 'Tubule segmentation')
            app.TubulesegmentationimageAxis.XTick = [];
            app.TubulesegmentationimageAxis.YTick = [];
            app.TubulesegmentationimageAxis.Visible = 'off';
            app.SATINNTitle = uilabel(app.MainGrid);
            app.SATINNTitle.Text = 'SATINN: An image classification tool for mouse seminiferous tubules';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SATINN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end